classdef SOLINDAR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        SOLINDARPanel  matlab.ui.container.Panel
        SonarButton    matlab.ui.control.StateButton
        LidarButton    matlab.ui.control.StateButton
        FusionButton   matlab.ui.control.StateButton
        ONSwitch       matlab.ui.control.Switch
        LogSwitch      matlab.ui.control.Switch
        LoggingLamp    matlab.ui.control.Lamp
        LoggingLabel   matlab.ui.control.Label
        SerialLabel    matlab.ui.control.Label
        SerialList     matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        % Serial
        s
        baudrate = 115200;
        buffersize = 8;
        % General parameters
        window = 1008;
        fs = 2800;
        posicion
        sonar
        lidar
        fusion
        fileID
    end
    
    methods (Access = private)
        % Callback para cuando se genera el evento de buffer lleno
        function readSerial(app)
            data = fread(app.s, app.buffersize, 'int8');
            [PositionRAW, LidarRAW, SonarRAW] = unpack(data);
            [Position, Lidar, Sonar] = ConvertData(PositionRAW, LidarRAW, SonarRAW);
            
            % Save the data if the logging is enable
            if strcmp(app.LogSwitch.Value, 'On')
                fprintf(app.fileID,'%s, %.2f, %.2f, %.2f\n', (datestr(datetime('now')))', round(Position(1), 3)', round(Lidar(1), 2)', round(Sonar(1), 2)');
            end
            
            % Plot
        end
        
        function initSerial(app)
            app.s.BaudRate = app.baudrate;
            app.s.InputBufferSize = app.buffersize;
            app.s.BytesAvailableFcnCount = app.buffersize;      % Número de bytes en el buffer de entrada para generar evento
            app.s.BytesAvailableFcnMode = 'byte';
            app.s.BytesAvailableFcn = @(~,~)app.readSerial;
        end
        
        function initPolarscatter(app)
            % Create polaraxes object
            pax = polaraxes(app.UIFigure);
            
            % Customize the polar axes
            pax.NextPlot = 'add';
            pax.ThetaZeroLocation = 'top';
            pax.ThetaDir = 'clockwise';
            pax.ThetaTickLabel = {'0', '30', '60', '90', '120', '150', '180', '-150', '-120', '-90', '-60', '-30'};
            pax.RLim = [0 200];
            pax.RTick = 0:50:200;
            pax.ThetaMinorGrid = 'on';
            pax.Position = [-0.03 0.1100 0.7750 0.7750];
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initPolarscatter;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if strcmp(app.s.Status, 'open')
                fclose(app.s);
            end
            %fclose(app.fileID);
            delete(app);
        end

        % Value changed function: SonarButton
        function SonarButtonValueChanged(app, event)
            value = app.SonarButton.Value;
            app.sonar.Visible = value;
        end

        % Value changed function: LidarButton
        function LidarButtonValueChanged(app, event)
            value = app.LidarButton.Value;
            app.lidar.Visible = value;
        end

        % Value changed function: FusionButton
        function FusionButtonValueChanged(app, event)
            value = app.FusionButton.Value;
            app.fusion.Visible = value;
        end

        % Value changed function: LogSwitch
        function LogSwitchValueChanged(app, event)
            value = app.LogSwitch.Value;
            app.LoggingLamp.Enable = value;
            if strcmp(value, 'On')
                % if the file exists read it, if it doesn't create it
                if isfile('log.csv')
                    app.fileID = fopen('log.csv', 'rt+');
                else
                    app.fileID = fopen('log.csv', 'wt+');
                end
                fseek(app.fileID, 0, 'bof');
                fprintf(app.fileID, 'Timestamp, Position, Lidar, Sonar\n');
                fseek(app.fileID, 0, 'eof');
            else
                fclose(app.fileID);
            end
            
        end

        % Value changed function: SerialList
        function SerialListValueChanged(app, event)
            value = app.SerialList.Value;
            app.SerialList.Items = seriallist;
        end

        % Value changed function: ONSwitch
        function ONSwitchValueChanged(app, event)
            value = app.ONSwitch.Value;
            if strcmp(value, 'Connect')
                app.s = serial(app.SerialList.Value);
                app.initSerial;
                fopen(app.s);
            else
               fclose(app.s);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'SOLINDAR APP';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SOLINDARPanel = uipanel(app.UIFigure);
            app.SOLINDARPanel.Title = 'SOLINDAR';
            app.SonarButton = uibutton(app.SOLINDARPanel, 'state');
            app.SonarButton.ValueChangedFcn = createCallbackFcn(app, @SonarButtonValueChanged, true);
            app.SonarButton.Text = 'SONAR';
            app.SonarButton.Value = true;
            app.LidarButton = uibutton(app.SOLINDARPanel, 'state');
            app.LidarButton.ValueChangedFcn = createCallbackFcn(app, @LidarButtonValueChanged, true);
            app.LidarButton.Text = 'LIDAR';
            app.LidarButton.Value = true;
            app.FusionButton = uibutton(app.SOLINDARPanel, 'state');
            app.FusionButton.ValueChangedFcn = createCallbackFcn(app, @FusionButtonValueChanged, true);
            app.FusionButton.Text = 'Fusión';
            app.FusionButton.Value = true;
            app.ONSwitch = uiswitch(app.SOLINDARPanel, 'slider');
            app.ONSwitch.Items = {'Disconnect', 'Connect'};
            app.ONSwitch.ValueChangedFcn = createCallbackFcn(app, @ONSwitchValueChanged, true);
            app.ONSwitch.Value = 'Disconnect';
            app.LogSwitch = uiswitch(app.SOLINDARPanel, 'slider');
            app.LogSwitch.ItemsData = {'Off', 'On'};
            app.LogSwitch.ValueChangedFcn = createCallbackFcn(app, @LogSwitchValueChanged, true);
            app.LoggingLamp = uilamp(app.SOLINDARPanel);
            app.LoggingLamp.Enable = 'off';
            app.LoggingLabel = uilabel(app.SOLINDARPanel);
            app.LoggingLabel.Text = 'Logging';
            app.SerialLabel = uilabel(app.SOLINDARPanel);
            app.SerialLabel.Text = 'Serial';
            app.SerialList = uidropdown(app.SOLINDARPanel);
            app.SerialList.Items = {'----------', 'Refresh'};
            app.SerialList.ValueChangedFcn = createCallbackFcn(app, @SerialListValueChanged, true);
            app.SerialList.Value = 'Refresh';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SOLINDAR

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end